classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        AvgLDPCPanel               matlab.ui.container.Panel
        avgLDPCt                   matlab.ui.control.Label
        avgLDPCTp                  matlab.ui.control.Label
        avgLDPCBER                 matlab.ui.control.Label
        secLabel_4                 matlab.ui.control.Label
        kbpsLabel_4                matlab.ui.control.Label
        TimeLabel_4                matlab.ui.control.Label
        ThroughtputLabel_4         matlab.ui.control.Label
        BERLabel_4                 matlab.ui.control.Label
        AvgQAMdemodPanel           matlab.ui.container.Panel
        avgQAMt                    matlab.ui.control.Label
        avgQAMTp                   matlab.ui.control.Label
        avgQAMBER                  matlab.ui.control.Label
        secLabel_3                 matlab.ui.control.Label
        kbpsLabel_3                matlab.ui.control.Label
        TimeLabel_3                matlab.ui.control.Label
        ThroughtputLabel_3         matlab.ui.control.Label
        BERLabel_3                 matlab.ui.control.Label
        SNRPanel                   matlab.ui.container.Panel
        SNR_RX1                    matlab.ui.control.Label
        SNR_RX2                    matlab.ui.control.Label
        dBLabel_2                  matlab.ui.control.Label
        dBLabel                    matlab.ui.control.Label
        Rx2SNRLabel                matlab.ui.control.Label
        Rx1SNRLabel                matlab.ui.control.Label
        LDPCPanel                  matlab.ui.container.Panel
        LDPC_BER                   matlab.ui.control.Label
        LDPC_C                     matlab.ui.control.Label
        CapacityLabel_2            matlab.ui.control.Label
        LDPCt                      matlab.ui.control.Label
        LDPCTp                     matlab.ui.control.Label
        secLabel_2                 matlab.ui.control.Label
        kbpsLabel_2                matlab.ui.control.Label
        BERLabel_2                 matlab.ui.control.Label
        ThroughputLabel_2          matlab.ui.control.Label
        TimeLabel_2                matlab.ui.control.Label
        QAMdemodPanel              matlab.ui.container.Panel
        QAM_BER                    matlab.ui.control.Label
        QAM_C                      matlab.ui.control.Label
        CapacityLabel              matlab.ui.control.Label
        QAMTp                      matlab.ui.control.Label
        QAMt                       matlab.ui.control.Label
        secLabel                   matlab.ui.control.Label
        kbpsLabel                  matlab.ui.control.Label
        TimeLabel                  matlab.ui.control.Label
        ThroughputLabel            matlab.ui.control.Label
        BERLabel                   matlab.ui.control.Label
        SetupPanel                 matlab.ui.container.Panel
        SNR                        matlab.ui.control.NumericEditField
        SNREditFieldLabel          matlab.ui.control.Label
        Time                       matlab.ui.control.NumericEditField
        TimeEditFieldLabel         matlab.ui.control.Label
        SNRweight                  matlab.ui.control.NumericEditField
        SNRweightEditFieldLabel_2  matlab.ui.control.Label
        Window                     matlab.ui.control.NumericEditField
        WindowEditFieldLabel_2     matlab.ui.control.Label
        dBLabel_3                  matlab.ui.control.Label
        mode                       matlab.ui.control.DropDown
        Label                      matlab.ui.control.Label
        DetectorChoose             matlab.ui.control.DropDown
        DetectorDropDownLabel      matlab.ui.control.Label
        StartButton                matlab.ui.control.Button
        Original_PHOTO             matlab.ui.control.UIAxes
        LDPC_PHOTO                 matlab.ui.control.UIAxes
        QAM_demodulate_PHOTO       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            cat_Origin=imread('cat.jpg');
            cat_Origin=imresize(cat_Origin,[350,350]);
            imshow(cat_Origin,'Parent',app.Original_PHOTO);
            pause(0.01);
            load('Tx_signal.mat');
            load('Frame_DATA.mat')
            detector_case = app.DetectorChoose.Value;       %選偵測器
            switch app.mode.Value
                case 'simulation'
                    SNR_rx1= 0;
                    SNR_rx2= 0;
                    sum_time_QAM= 0;
                    sum_time_LDPC= 0;
                    biterror_all_QAM= 0;
                    biterror_all_LDPC= 0;
                    %                     Capacity_sum_all_QAM= 0;
                    Capacity_sum_all_LDPC= 0;

                    for i=1:app.Time.Value
                        Tx_num=2;
                        Rx_num=2;
                        blank_space=100;        %前後留下空間
                        No  = 10.^(-app.SNR.Value/10);
                        PowerdB = [-2 -8 -10 -12 -15 -18].';
                        PowerdB_MIMO= repmat(PowerdB,1,Rx_num,Tx_num);
                        H_Channel = sqrt(10.^(PowerdB_MIMO./10)).* sqrt(1/Tx_num );
                        Ntap=6;
                        H_Channel = H_Channel .* ( sqrt( 1/2 ).* ( randn(Ntap,Rx_num,Tx_num) + 1i*randn(Ntap,Rx_num,Tx_num) ) );
                        y= zeros(Rx_num,1228800+5);
                        for i= 1:Tx_num
                            for j= 1:Rx_num
                                y(j,:)=y(j,:)+conv(Tx_signal(1,:,i),H_Channel(:,j,i) );
                            end
                        end
                        newy=y(:,1:end-5);
                        n = sqrt(No/2) * ( randn(Tx_num,1228800+2*blank_space) + randn(Tx_num,1228800+2*blank_space)*1i );
                        Rx_signal=[zeros(2,blank_space),newy,zeros(2,blank_space)]+n; % y with noise

                        load('LDPC_11nD2_1296b_R12.mat');
                        load('Frame_DATA.mat')
                        DMRS_value=0.7071 + 0.7071*1i ;
                        delta_f= 1000;
                        QAM=16;
                        q_bit=log2(16);
                        xxx=(0:QAM-1);
                        yyy=qammod(xxx,QAM);
                        Eavg=mean(abs(yyy).^2);
                        NF=1/sqrt(Eavg);
                        bin_table= 2.^(7:-1:0);
                        N=2048;
                        R_HD_HD	=zeros( 822,822);
                        R_H_HD	=zeros(1644,822);
                        DMRS_pos=[204:2:1024,1027:2:1847];
                        Real_pos=[203:1:1024,1026:1:1847];
                        for p=1:822
                            for k=1:822%R_HD_HD 822*822
                                if DMRS_pos(k)==DMRS_pos(p)
                                    R_HD_HD(k,p)=1;
                                else
                                    R_HD_HD(k,p)=( 1-exp(-1i*2*pi*app.Window.Value*(DMRS_pos(k)-DMRS_pos(p))/N))/( 1i*2*pi*app.Window.Value*(DMRS_pos(k)-DMRS_pos(p))/N );
                                end
                            end
                            for k=1:1644
                                if Real_pos(k)==DMRS_pos(p)
                                    R_H_HD(k,p)=1;
                                else
                                    R_H_HD(k,p)=(1-exp(-1i*2*pi*app.Window.Value*(Real_pos(k)-DMRS_pos(p))/N))/( 1i*2*pi*app.Window.Value*(Real_pos(k)-DMRS_pos(p))/N );
                                end
                            end
                        end
                        SNR_Power=10.^(double(app.SNRweight.Value)/10);

                        %LDPC init
                        HLDPC = double(LDPC.H.x);
                        [row,col]	= find(HLDPC==1);
                        [A,I] = sort(row);
                        H_row_master 	  = zeros(648,8);
                        H_row_master_size = ones(648,1);

                        frameTable=zeros(1644,560,Tx_num);
                        frameTable(2:2:1644,3:14:560,:)= 1;
                        frameTable(2:4:1644,3:14:560,2:2:Tx_num)=-1;
                        frameTable(:,5,:)=2;%% PSS

                        DATA_Pos=find(frameTable==0);

                        for i=1:4644
                            H_row_master( A(i) , H_row_master_size( A(i)) ) = col(I(i));
                            H_row_master_size(A(i)) = H_row_master_size(A(i)) + 1;
                        end
                        H_row_master_size = H_row_master_size - 1;
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% xcorr %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        tic
                        PSS_GB=[zeros(202,1);Frame_DATA.PSS(1:822);0;Frame_DATA.PSS(823:end);zeros(201,1)];
                        PSS_t = ifft(ifftshift(PSS_GB,1))*sqrt(2048);
                        [corr(1,:),corr_pos1]=xcorr(Rx_signal(1,1:2457600/2),PSS_t);
                        [corr(2,:),corr_pos2]=xcorr(Rx_signal(2,1:2457600/2),PSS_t);
                        [~, startpositon1]=max(abs(corr(1,:)));
                        [~, startpositon2]=max(abs(corr(2,:)));
                        y_pos1=corr_pos1(startpositon1)-8977;%8913
                        y_pos2=corr_pos2(startpositon2)-8977;%8977
                        y_synb=zeros(2,1228800);
                        y_synb(1,:)=Rx_signal(1,y_pos1:y_pos1+1228800-1);
                        y_synb(2,:)=Rx_signal(2,y_pos2:y_pos2+1228800-1);%%大小2*1228800

                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% CFO %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        t = (10e-3/1228800):(10e-3/1228800):10e-3;
                        y_synb= exp(1i*2*pi*delta_f*t).*y_synb;

                        index1 = 1;
                        CFO_sum= 0;
                        CP_num= 0;
                        for symbol = 1:560
                            if (mod(symbol,28)-1)
                                CFO_head=y_synb(:,index1: index1+143);
                                CFO_tail=y_synb(:, index1+2048: index1+143+2048 );
                                CFO_sum=CFO_sum+ sum(CFO_head .* (CFO_tail').','all');
                                CP_num=CP_num + 144;
                                index1=index1 + 144 + 2048;
                            else
                                CFO_head=y_synb( :,index1: index1+207 );
                                CFO_tail=y_synb( :,index1+2048: index1+207+2048 );
                                CFO_sum=CFO_sum+ sum(CFO_head .* (CFO_tail').','all');
                                CP_num=CP_num + 208;
                                index1=index1+208+2048;
                            end

                        end
                        %偏移乘回來
                        hat_delta_f =-(Frame_DATA.Fs/2048)*angle(CFO_sum/CP_num)/(2*pi) ;
                        y_syna=y_synb./exp(1i*2*pi*hat_delta_f*t);

                        %移除CP
                        yhasnoCP= zeros(2048,560,Tx_num);
                        index1  = 1;
                        for symbol = 1:560
                            if (mod(symbol,28)-1)
                                for k = 1:Rx_num
                                    yhasnoCP(:,symbol,k) = y_syna(k,index1+144:index1+144+2048-1);
                                end
                                index1  = index1+144+2048;
                            else
                                for k = 1:Rx_num
                                    yhasnoCP(:,symbol,k) = y_syna(k,index1+208:index1+208+2048-1);
                                end
                                index1  = index1 +208+2048;
                            end
                        end
                        %FFT----以下為頻域
                        Y_fft = fftshift( fft( yhasnoCP/sqrt(2048) ) ,1);
                        %rm GB
                        Y= [ Y_fft( 203:1024,:,:) ; Y_fft( 1026:1847,:,:) ];
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%通道估測%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        Y_DMRS=Y(2:2:1644,3:14:560,:);

                        P0 = eye(822);
                        P1 = ones(822,1);
                        P1(1:2:822)=-1;
                        P1 = diag(P1);
                        H_LMMSE=zeros(1644,40,2,2);
                        H_LMMSE(:,:,1,1)=R_H_HD*P0'*inv(P0*R_HD_HD*P0'+P1*R_HD_HD*P1'+(1./SNR_Power).*P0)*(DMRS_value'.*Y_DMRS(:,:,1));
                        H_LMMSE(:,:,2,1)=R_H_HD*P0'*inv(P0*R_HD_HD*P0'+P1*R_HD_HD*P1'+(1./SNR_Power).*P0)*(DMRS_value'.*Y_DMRS(:,:,2));
                        H_LMMSE(:,:,1,2)=R_H_HD*P1'*inv(P0*R_HD_HD*P0'+P1*R_HD_HD*P1'+(1./SNR_Power).*P0)*(DMRS_value'.*Y_DMRS(:,:,1));
                        H_LMMSE(:,:,2,2)=R_H_HD*P1'*inv(P0*R_HD_HD*P0'+P1*R_HD_HD*P1'+(1./SNR_Power).*P0)*(DMRS_value'.*Y_DMRS(:,:,2));
                        %線性內差
                        H_LI=zeros(1644,560,2,2);
                        DMRS_ps=3:14:560;
                        for symbol=1:560
                            if symbol>=1 && symbol<=2  %前邊界
                                foreward=11+symbol;
                                backward=3-symbol;
                                H_LI(:,symbol,:,:)=(foreward*H_LMMSE(:,1,:,:))/14+(backward*H_LMMSE(:,40,:,:))/14;
                            end

                            if symbol>=3 && symbol<=548 % 中間
                                location=floor((symbol-3)/14)+1;
                                foreward=symbol-DMRS_ps(location);
                                backward=DMRS_ps(location+1)-symbol;
                                H_LI(:,symbol,:,:)=(foreward*H_LMMSE(:,location+1,:,:))/14+backward*H_LMMSE(:,location,:,:)/14;
                            end
                            if symbol>=549 && symbol<=560 %後邊界
                                foreward=symbol-549;
                                backward=563-symbol;
                                H_LI(:,symbol,:,:)=(foreward*H_LMMSE(:,1,:,:))/14+(backward*H_LMMSE(:,40,:,:))/14;
                            end
                        end

                        DMRS= Frame_DATA.LDPC_mod_data(2:2:1644 , 3:14:560,:);
                        DMRS_H=H_LMMSE(2:2:1644,:,:,:);
                        DMRS_hat_Y=zeros(822,40,2);
                        for carrier=1:822
                            for symbol=1:40
                                DMRS_hat_Y(carrier,symbol,:) = reshape(DMRS_H(carrier,symbol,:,:),2,2) * reshape(DMRS(carrier,symbol,:),2,1);
                            end
                        end
                        Rx1_No = sum( abs( Y_DMRS(:,:,1)  - DMRS_hat_Y(:,:,1) ).^2 ,'all' )/(40*822);
                        Rx2_No = sum( abs( Y_DMRS(:,:,2)  - DMRS_hat_Y(:,:,2) ).^2 ,'all' )/(40*822);
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%選擇detector%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        NM_Y = zeros(1644,560,2);
                        NM_H = zeros(1644,560,2,2);
                        NM_Y(:,:,1) = Y(:,:,1)./ Rx1_No;
                        NM_Y(:,:,2) = Y(:,:,2)./ Rx2_No;
                        NM_H(:,:,1,:) = H_LI(:,:,1,:) ./ Rx1_No;
                        NM_H(:,:,2,:) = H_LI(:,:,2,:) ./ Rx2_No;
                        X_hat_L=zeros(1644,560,Tx_num);
                        switch detector_case
                            case 'ZF'
                                X_hat_L=ZF(NM_Y,NM_H,Tx_num);

                            case 'LMMSE'

                                X_hat_L=LMMSE(NM_Y,NM_H,Tx_num);
                        end
                        X_hat_L=X_hat_L/NF;
                        LDPC_mod_hat= X_hat_L(DATA_Pos(1:1472256));
                        %     LDPC_mod_hat= LDPC_mod_hat(1:1472256);

                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% LLR_MOS %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                        y_i=real(LDPC_mod_hat).';       %inphase
                        y_q=imag(LDPC_mod_hat).';       %quadrature
                        y_LLR=zeros(q_bit*length(LDPC_mod_hat),1);

                        y_LLR(1:4:length(y_LLR))=(1/(2*No))*(min((y_i-  1) .^2,( y_i-  3) .^2 )-min((y_i-(-1)).^2,( y_i-(-3)).^ 2));
                        y_LLR(2:4:length(y_LLR))=(1/(2*No))*(min((y_i-(-1)).^2,( y_i-  1) .^2 )-min((y_i-(-3)).^2,( y_i-  3 ).^ 2));
                        y_LLR(3:4:length(y_LLR))=(1/(2*No))*(min((y_q-(-1)).^2,( y_q-(-3)).^2 )-min((y_q-  1) .^2,( y_q-  3 ).^ 2));
                        y_LLR(4:4:length(y_LLR))=(1/(2*No))*(min((y_q-(-1)).^2,( y_q-  1) .^2 )-min((y_q-(-3)).^2,( y_q-  3 ).^ 2));
                        y_LLR=reshape(y_LLR,1296,4544);
                        LLR_part= reshape(y_LLR,4544,1296);
                        LLR_part= permute(LLR_part,[2,1]);
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 解碼 %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                        LLR_OMS= LDPC_OMS_re(LLR_part,H_row_master,H_row_master_size,4544);%OMS
                        cat_bin_hat_LDPC = reshape(LLR_OMS(1:648,:).'<0,[],8);

                        Times=toc;

                        cat_Origin=imread('cat.jpg');
                        cat_Origin=imresize(cat_Origin,[350,350]);
                        cat_Ori_binary=[dec2bin(cat_Origin(:,:,1));dec2bin(cat_Origin(:,:,2));dec2bin(cat_Origin(:,:,3))];
                        cat_size=size(cat_Ori_binary,1);
                        cat_row=size(cat_Origin,1);         %圖片的長寬
                        cat_col=size(cat_Origin,2);
                        cat_Csize=cat_row*cat_col;

                        BER_LDPC=0;
                        cat_Ori_binary2=zeros(368064,8);
                        cat_Ori_binary2(1:367500,:)=cat_Ori_binary(1:367500,:)-'0';
                        BER_LDPC=BER_LDPC+sum((cat_Ori_binary2)~=cat_bin_hat_LDPC,'all');

                        cat_bin_hat_LDPC = cat_bin_hat_LDPC(1:cat_size,:);
                        cat_bin_hat_LDPC = sum(cat_bin_hat_LDPC.*bin_table,2);%bin2dec
                        cat_RGB_LDPC= zeros(cat_row,cat_col,3);
                        cat_RGB_LDPC(:,:,1) = reshape( cat_bin_hat_LDPC(            1:cat_Csize  ) ,cat_row,cat_col);
                        cat_RGB_LDPC(:,:,2) = reshape( cat_bin_hat_LDPC(cat_Csize  +1:cat_Csize*2) ,cat_row,cat_col);
                        cat_RGB_LDPC(:,:,3) = reshape( cat_bin_hat_LDPC(cat_Csize*2+1:cat_Csize*3) ,cat_row,cat_col);
                        %     imshow( uint8(cat_RGB_LDPC) );
                        %     BER_LDPC=0;
                        %     BER_LDPC=BER_LDPC+sum((cat_Ori_binary-'0')~=cat_bin_hat_LDPC,'all');

                        %計算capacity
                        SNR = 1/No;
                        Capacity=0;
                        for carrier = 1:1644
                            for slot = 1:560
                                unit_H = reshape(H_LI(carrier,slot,:,:),Rx_num,Tx_num);
                                Capacity=Capacity+abs(log2( det( eye(Tx_num)+ (1/Tx_num).* SNR .* (unit_H*unit_H'))));
                            end
                        end

                        SNR_rx1 =-10*log10(No/ mean(abs(H_LMMSE(:,:,:,1) ).^2,'all'));
                        SNR_rx2 =-10*log10(No/ mean(abs(H_LMMSE(:,:,:,2) ).^2,'all'));


                        Capacity_sum_all_LDPC = Capacity_sum_all_LDPC + Capacity;


                        BER = BER_LDPC/numel(Frame_DATA.cat_Ori_binary);
                        app.LDPCt.Text = num2str(Times);                                %%用時間
                        app.LDPC_BER.Text = num2str(BER ,'%2.2e');                       %%BER
                        app.LDPCTp.Text = num2str(numel(Frame_DATA.cat_Ori_binary)*(1-BER)/(Times*10^3),'%.1f');%%throughtput
                        app.LDPC_C.Text = num2str(Capacity /(1644*560) );
                        
                        imshow(uint8(cat_RGB_LDPC),'Parent',app.LDPC_PHOTO);
                        pause(0.01);

                        sum_time_LDPC= sum_time_LDPC+ Times;
                        biterror_all_LDPC = biterror_all_LDPC + BER;

                        app.SNR_RX2.Text = num2str(SNR_rx2);
                        app.SNR_RX1.Text = num2str(SNR_rx1);

                        %                     BER_avg = biterror_all_QAM/(numel(Frame_DATA.cat_Ori_binary)*app.Time.Value);
                        %                     BER_avg_LDPC= biterror_all_LDPC/(numel(Frame_DATA.cat_Ori_binary)*app.Time.Value);
                        %
                        %                     app.avgQAMt.Text  = num2str(sum_time_QAM/app.Time.Value);
                        %                     app.avgLDPCt.Text = num2str(sum_time_LDPC/app.Time.Value);
                        %
                        %                     app.avgQAMBER.Text = num2str( BER_avg,'%2.2e');
                        %                     app.avgLDPCBER.Text = num2str( BER_avg_LDPC ,'%2.2e');
                        %                     app.avgQAMTp.Text = num2str(app.Time.Value * numel(Frame_DATA.cat_Ori_binary)*(1-BER_avg)/(sum_time_QAM*10^3) ,'%.1f');
                        %                     app.avgLDPCTp.Text = num2str(app.Time.Value * numel(Frame_DATA.cat_Ori_binary)*(1-BER_avg_LDPC)/(sum_time_LDPC*10^3),'%.1f');
                        %
                        %                     app.aQAM_C.Text = num2str( Capacity_sum_all / (1644*560) );
                        %                     app.aLDPC_C.Text = num2str( Capacity_sum_all_LDPC / (1644*560) );


                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


                        tic
                        PSS_GB_2=[zeros(202,1);Frame_DATA.PSS(1:822);0;Frame_DATA.PSS(823:end);zeros(201,1)];
                        PSS_t_2 = ifft(ifftshift(PSS_GB_2,1))*sqrt(2048);
                        [corr2(1,:),corr_pos1_2]=xcorr(Rx_signal(1,1:2457600/2),PSS_t_2);
                        [corr2(2,:),corr_pos2_2]=xcorr(Rx_signal(2,1:2457600/2),PSS_t_2);
                        [~, startpositon1_2]=max(abs(corr2(1,:)));
                        [~, startpositon2_2]=max(abs(corr2(2,:)));
                        y_pos1_2=corr_pos1_2(startpositon1_2)-8977;%8913
                        y_pos2_2=corr_pos2_2(startpositon2_2)-8977;%8977
                        y_synb_2=zeros(2,1228800);
                        y_synb_2(1,:)=Rx_signal(1,y_pos1_2:y_pos1_2+1228800-1);
                        y_synb_2(2,:)=Rx_signal(2,y_pos2_2:y_pos2_2+1228800-1);%%大小2*1228800

                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% CFO %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        t_2 = (10e-3/1228800):(10e-3/1228800):10e-3;
                        y_synb_2= exp(1i*2*pi*delta_f*t_2).*y_synb_2;

                        index1 = 1;
                        CFO_sum= 0;
                        CP_num= 0;
                        for symbol = 1:560
                            if (mod(symbol,28)-1)
                                CFO_head=y_synb_2(:,index1: index1+143);
                                CFO_tail=y_synb_2(:, index1+2048: index1+143+2048 );
                                CFO_sum=CFO_sum+ sum(CFO_head .* (CFO_tail').','all');
                                CP_num=CP_num + 144;
                                index1=index1 + 144 + 2048;
                            else
                                CFO_head=y_synb_2( :,index1: index1+207 );
                                CFO_tail=y_synb_2( :,index1+2048: index1+207+2048 );
                                CFO_sum=CFO_sum+ sum(CFO_head .* (CFO_tail').','all');
                                CP_num=CP_num + 208;
                                index1=index1+208+2048;
                            end

                        end
                        %偏移乘回來
                        hat_delta_f_2 =-(Frame_DATA.Fs/2048)*angle(CFO_sum/CP_num)/(2*pi) ;
                        y_syna_2=y_synb_2./exp(1i*2*pi*hat_delta_f_2*t_2);

                        %移除CP
                        yhasnoCP_2= zeros(2048,560,Tx_num);
                        index1  = 1;
                        for symbol = 1:560
                            if (mod(symbol,28)-1)
                                for k = 1:Rx_num
                                    yhasnoCP_2(:,symbol,k) = y_syna_2(k,index1+144:index1+144+2048-1);
                                end
                                index1  = index1+144+2048;
                            else
                                for k = 1:Rx_num
                                    yhasnoCP_2(:,symbol,k) = y_syna_2(k,index1+208:index1+208+2048-1);
                                end
                                index1  = index1 +208+2048;
                            end
                        end
                        %FFT----以下為頻域
                        Y_fft_2 = fftshift( fft( yhasnoCP_2/sqrt(2048) ) ,1);
                        %rm GB
                        Y_2= [ Y_fft_2( 203:1024,:,:) ; Y_fft_2( 1026:1847,:,:) ];
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%通道估測%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        Y_DMRS_2=Y_2(2:2:1644,3:14:560,:);

                        P0 = eye(822);
                        P1 = ones(822,1);
                        P1(1:2:822)=-1;
                        P1 = diag(P1);
                        H_LMMSE_2=zeros(1644,40,2,2);
                        H_LMMSE_2(:,:,1,1)=R_H_HD*P0'*inv(P0*R_HD_HD*P0'+P1*R_HD_HD*P1'+(1./SNR_Power).*P0)*(DMRS_value'.*Y_DMRS_2(:,:,1));
                        H_LMMSE_2(:,:,2,1)=R_H_HD*P0'*inv(P0*R_HD_HD*P0'+P1*R_HD_HD*P1'+(1./SNR_Power).*P0)*(DMRS_value'.*Y_DMRS_2(:,:,2));
                        H_LMMSE_2(:,:,1,2)=R_H_HD*P1'*inv(P0*R_HD_HD*P0'+P1*R_HD_HD*P1'+(1./SNR_Power).*P0)*(DMRS_value'.*Y_DMRS_2(:,:,1));
                        H_LMMSE_2(:,:,2,2)=R_H_HD*P1'*inv(P0*R_HD_HD*P0'+P1*R_HD_HD*P1'+(1./SNR_Power).*P0)*(DMRS_value'.*Y_DMRS_2(:,:,2));
                        %線性內差
                        H_LI_2=zeros(1644,560,2,2);
                        DMRS_ps_2=3:14:560;
                        for symbol=1:560
                            if symbol>=1 && symbol<=2  %前邊界
                                foreward_2=11+symbol;
                                backward_2=3-symbol;
                                H_LI_2(:,symbol,:,:)=(foreward_2*H_LMMSE_2(:,1,:,:))/14+(backward_2*H_LMMSE_2(:,40,:,:))/14;
                            end

                            if symbol>=3 && symbol<=548 % 中間
                                location=floor((symbol-3)/14)+1;
                                foreward_2=symbol-DMRS_ps_2(location);
                                backward_2=DMRS_ps_2(location+1)-symbol;
                                H_LI_2(:,symbol,:,:)=(foreward_2*H_LMMSE_2(:,location+1,:,:))/14+backward_2*H_LMMSE_2(:,location,:,:)/14;
                            end
                            if symbol>=549 && symbol<=560 %後邊界
                                foreward_2=symbol-549;
                                backward_2=563-symbol;
                                H_LI_2(:,symbol,:,:)=(foreward_2*H_LMMSE_2(:,1,:,:))/14+(backward_2*H_LMMSE_2(:,40,:,:))/14;
                            end
                        end

                        DMRS_2= Frame_DATA.LDPC_mod_data(2:2:1644 , 3:14:560,:);
                        DMRS_H_2=H_LMMSE_2(2:2:1644,:,:,:);
                        DMRS_hat_Y_2=zeros(822,40,2);
                        for carrier=1:822
                            for symbol=1:40
                                DMRS_hat_Y_2(carrier,symbol,:) = reshape(DMRS_H_2(carrier,symbol,:,:),2,2) * reshape(DMRS_2(carrier,symbol,:),2,1);
                            end
                        end
                        Rx1_No_2 = sum( abs( Y_DMRS_2(:,:,1)  - DMRS_hat_Y_2(:,:,1) ).^2 ,'all' )/(40*822);
                        Rx2_No_2 = sum( abs( Y_DMRS_2(:,:,2)  - DMRS_hat_Y_2(:,:,2) ).^2 ,'all' )/(40*822);
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%選擇detector%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        NM_Y_2 = zeros(1644,560,2);
                        NM_H_2 = zeros(1644,560,2,2);
                        NM_Y_2(:,:,1) = Y_2(:,:,1)./ Rx1_No_2;
                        NM_Y_2(:,:,2) = Y_2(:,:,2)./ Rx2_No_2;
                        NM_H_2(:,:,1,:) = H_LI_2(:,:,1,:) ./ Rx1_No_2;
                        NM_H_2(:,:,2,:) = H_LI_2(:,:,2,:) ./ Rx2_No_2;
                        X_hat_L_2=zeros(1644,560,Tx_num);
                        switch detector_case
                            case 'ZF'
                                X_hat_L_2=ZF(NM_Y_2,NM_H_2,Tx_num);

                            case 'LMMSE'

                                X_hat_L_2=LMMSE(NM_Y_2,NM_H_2,Tx_num);
                        end
                        X_hat_L_2=X_hat_L_2/NF;
                        LDPC_mod_hat_2= X_hat_L_2(DATA_Pos(1:1472256));
                        %     LDPC_mod_hat= LDPC_mod_hat(1:1472256);
                        QAM_dec_L_hat = qamdemod(LDPC_mod_hat_2,QAM ,'gray');
                        QAM_bin_L_hat = reshape(dec2bin (QAM_dec_L_hat,q_bit).'-'0',[],1) ;
                        LDPC_bin_part  = permute(reshape(QAM_bin_L_hat,[],1296) ,[2,1]);
                        cat_bin_hat   = reshape(LDPC_bin_part(1:648,:).',[],8);
                        Times2=toc;


                        cat_Origin=imread('cat.jpg');
                        cat_Origin=imresize(cat_Origin,[350,350]);
                        cat_Ori_binary=[dec2bin(cat_Origin(:,:,1));dec2bin(cat_Origin(:,:,2));dec2bin(cat_Origin(:,:,3))];
                        %                     cat_size=size(cat_Ori_binary,1);
                        %                     cat_row=size(cat_Origin,1);         %圖片的長寬
                        %                     cat_col=size(cat_Origin,2);
                        %                     cat_Csize=cat_row*cat_col;

                        BER_QAM=0;
                        cat_Ori_binary2=zeros(368064,8);
                        cat_Ori_binary2(1:367500,:)=cat_Ori_binary(1:367500,:)-'0';
                        BER_QAM=BER_QAM+sum((cat_Ori_binary2)~=cat_bin_hat,'all');

                        cat_bin_hat = cat_bin_hat(1:cat_size,:);
                        cat_bin_hat = sum(cat_bin_hat.*bin_table,2);%bin2dec
                        cat_RGB_LDPC2= zeros(cat_row,cat_col,3);
                        cat_RGB_LDPC2(:,:,1) = reshape( cat_bin_hat(            1:cat_Csize  ) ,cat_row,cat_col);
                        cat_RGB_LDPC2(:,:,2) = reshape( cat_bin_hat(cat_Csize  +1:cat_Csize*2) ,cat_row,cat_col);
                        cat_RGB_LDPC2(:,:,3) = reshape( cat_bin_hat(cat_Csize*2+1:cat_Csize*3) ,cat_row,cat_col);


                        BER2 = BER_QAM/numel(Frame_DATA.cat_Ori_binary);
                        app.QAMt.Text = num2str(Times2);                                %%用時間
                        app.QAM_BER.Text = num2str(BER2 ,'%2.2e');                       %%BER
                        app.QAM_C.Text = num2str(Capacity /(1644*560) );
                        app.QAMTp.Text = num2str(numel(Frame_DATA.cat_Ori_binary)*(1-BER2)/(Times*10^3),'%.1f');%%throughtput
                        %                     app.QAM_C.Text = num2str(Capacity /(1644*560) );
                        imshow(uint8(cat_RGB_LDPC2),'Parent',app.QAM_demodulate_PHOTO);
                        pause(0.01);

                        sum_time_QAM= sum_time_QAM+ Times;
                        biterror_all_QAM = biterror_all_QAM + BER2;

                    end
                    BER_avg = biterror_all_QAM/(numel(Frame_DATA.cat_Ori_binary)*app.Time.Value);
                    BER_avg_LDPC= biterror_all_LDPC/(numel(Frame_DATA.cat_Ori_binary)*app.Time.Value);

                    app.avgQAMt.Text  = num2str(sum_time_QAM/app.Time.Value);
                    app.avgLDPCt.Text = num2str(sum_time_LDPC/app.Time.Value);

                    app.avgQAMBER.Text = num2str( BER_avg,'%2.2e');
                    app.avgLDPCBER.Text = num2str( BER_avg_LDPC ,'%2.2e');
                    app.avgQAMTp.Text = num2str(app.Time.Value * numel(Frame_DATA.cat_Ori_binary)*(1-BER_avg)/(sum_time_QAM*10^3) ,'%.1f');
                    app.avgLDPCTp.Text = num2str(app.Time.Value * numel(Frame_DATA.cat_Ori_binary)*(1-BER_avg_LDPC)/(sum_time_LDPC*10^3),'%.1f');
                    app.aQAM_C.Text = num2str( Capacity_sum_all / (1644*560) );
                    app.aLDPC_C.Text = num2str( Capacity_sum_all_LDPC / (1644*560) );

                case 'OTA'%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    SNR_rx1= 0;
                    SNR_rx2= 0;
                    sum_time_QAM= 0;
                    sum_time_LDPC= 0;
                    biterror_all_QAM= 0;
                    biterror_all_LDPC= 0;
                    % Capacity_sum_all_QAM= 0;
                    Capacity_sum_all_LDPC= 0;
                    
                    for i=1:app.Time.Value
                        Tx_num=2;
                        Rx_num=2;
                        No  = 10.^(-app.SNR.Value/10);
                        load('Rx_signal.mat');

                        load('LDPC_11nD2_1296b_R12.mat');
                        load('Frame_DATA.mat')
                        DMRS_value=0.7071 + 0.7071*1i ;
                        delta_f= 1000;
                        QAM=16;
                        q_bit=log2(16);
                        xxx=(0:QAM-1);
                        yyy=qammod(xxx,QAM);
                        Eavg=mean(abs(yyy).^2);
                        NF=1/sqrt(Eavg);
                        bin_table= 2.^(7:-1:0);
                        N=2048;
                        R_HD_HD	=zeros( 822,822);
                        R_H_HD	=zeros(1644,822);
                        DMRS_pos=[204:2:1024,1027:2:1847];
                        Real_pos=[203:1:1024,1026:1:1847];
                        for p=1:822
                            for k=1:822%R_HD_HD 822*822
                                if DMRS_pos(k)==DMRS_pos(p)
                                    R_HD_HD(k,p)=1;
                                else
                                    R_HD_HD(k,p)=( 1-exp(-1i*2*pi*app.Window.Value*(DMRS_pos(k)-DMRS_pos(p))/N))/( 1i*2*pi*app.Window.Value*(DMRS_pos(k)-DMRS_pos(p))/N );
                                end
                            end
                            for k=1:1644
                                if Real_pos(k)==DMRS_pos(p)
                                    R_H_HD(k,p)=1;
                                else
                                    R_H_HD(k,p)=(1-exp(-1i*2*pi*app.Window.Value*(Real_pos(k)-DMRS_pos(p))/N))/( 1i*2*pi*app.Window.Value*(Real_pos(k)-DMRS_pos(p))/N );
                                end
                            end
                        end
                        SNR_Power=10.^(double(app.SNRweight.Value)/10);

                        %LDPC init
                        HLDPC = double(LDPC.H.x);
                        [row,col]	= find(HLDPC==1);
                        [A,I] = sort(row);
                        H_row_master 	  = zeros(648,8);
                        H_row_master_size = ones(648,1);

                        frameTable=zeros(1644,560,Tx_num);
                        frameTable(2:2:1644,3:14:560,:)= 1;
                        frameTable(2:4:1644,3:14:560,2:2:Tx_num)=-1;
                        frameTable(:,5,:)=2;%% PSS

                        DATA_Pos=find(frameTable==0);

                        for i=1:4644
                            H_row_master( A(i) , H_row_master_size( A(i)) ) = col(I(i));
                            H_row_master_size(A(i)) = H_row_master_size(A(i)) + 1;
                        end
                        H_row_master_size = H_row_master_size - 1;
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% xcorr %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        tic
                        PSS_GB=[zeros(202,1);Frame_DATA.PSS(1:822);0;Frame_DATA.PSS(823:end);zeros(201,1)];
                        PSS_t = ifft(ifftshift(PSS_GB,1))*sqrt(2048);
                        [corr(1,:),corr_pos1]=xcorr(Rx_signal(1,1:2457600/2),PSS_t);
                        [corr(2,:),corr_pos2]=xcorr(Rx_signal(2,1:2457600/2),PSS_t);
                        [~, startpositon1]=max(abs(corr(1,:)));
                        [~, startpositon2]=max(abs(corr(2,:)));
                        y_pos1=corr_pos1(startpositon1)-8977;%8913
                        y_pos2=corr_pos2(startpositon2)-8977;%8977
                        y_synb=zeros(2,1228800);
                        y_synb(1,:)=Rx_signal(1,y_pos1:y_pos1+1228800-1);
                        y_synb(2,:)=Rx_signal(2,y_pos2:y_pos2+1228800-1);%%大小2*1228800

                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% CFO %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        t = (10e-3/1228800):(10e-3/1228800):10e-3;
                        y_synb= exp(1i*2*pi*delta_f*t).*y_synb;

                        index1 = 1;
                        CFO_sum= 0;
                        CP_num= 0;
                        for symbol = 1:560
                            if (mod(symbol,28)-1)
                                CFO_head=y_synb(:,index1: index1+143);
                                CFO_tail=y_synb(:, index1+2048: index1+143+2048 );
                                CFO_sum=CFO_sum+ sum(CFO_head .* (CFO_tail').','all');
                                CP_num=CP_num + 144;
                                index1=index1 + 144 + 2048;
                            else
                                CFO_head=y_synb( :,index1: index1+207 );
                                CFO_tail=y_synb( :,index1+2048: index1+207+2048 );
                                CFO_sum=CFO_sum+ sum(CFO_head .* (CFO_tail').','all');
                                CP_num=CP_num + 208;
                                index1=index1+208+2048;
                            end

                        end
                        %偏移乘回來
                        hat_delta_f =-(Frame_DATA.Fs/2048)*angle(CFO_sum/CP_num)/(2*pi) ;
                        y_syna=y_synb./exp(1i*2*pi*hat_delta_f*t);

                        %移除CP
                        yhasnoCP= zeros(2048,560,Tx_num);
                        index1  = 1;
                        for symbol = 1:560
                            if (mod(symbol,28)-1)
                                for k = 1:Rx_num
                                    yhasnoCP(:,symbol,k) = y_syna(k,index1+144:index1+144+2048-1);
                                end
                                index1  = index1+144+2048;
                            else
                                for k = 1:Rx_num
                                    yhasnoCP(:,symbol,k) = y_syna(k,index1+208:index1+208+2048-1);
                                end
                                index1  = index1 +208+2048;
                            end
                        end
                        %FFT----以下為頻域
                        Y_fft = fftshift( fft( yhasnoCP/sqrt(2048) ) ,1);
                        %rm GB
                        Y= [ Y_fft( 203:1024,:,:) ; Y_fft( 1026:1847,:,:) ];
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%通道估測%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        Y_DMRS=Y(2:2:1644,3:14:560,:);

                        P0 = eye(822);
                        P1 = ones(822,1);
                        P1(1:2:822)=-1;
                        P1 = diag(P1);
                        H_LMMSE=zeros(1644,40,2,2);
                        H_LMMSE(:,:,1,1)=R_H_HD*P0'*inv(P0*R_HD_HD*P0'+P1*R_HD_HD*P1'+(1./SNR_Power).*P0)*(DMRS_value'.*Y_DMRS(:,:,1));
                        H_LMMSE(:,:,2,1)=R_H_HD*P0'*inv(P0*R_HD_HD*P0'+P1*R_HD_HD*P1'+(1./SNR_Power).*P0)*(DMRS_value'.*Y_DMRS(:,:,2));
                        H_LMMSE(:,:,1,2)=R_H_HD*P1'*inv(P0*R_HD_HD*P0'+P1*R_HD_HD*P1'+(1./SNR_Power).*P0)*(DMRS_value'.*Y_DMRS(:,:,1));
                        H_LMMSE(:,:,2,2)=R_H_HD*P1'*inv(P0*R_HD_HD*P0'+P1*R_HD_HD*P1'+(1./SNR_Power).*P0)*(DMRS_value'.*Y_DMRS(:,:,2));
                        %線性內差
                        H_LI=zeros(1644,560,2,2);
                        DMRS_ps=3:14:560;
                        for symbol=1:560
                            if symbol>=1 && symbol<=2  %前邊界
                                foreward=11+symbol;
                                backward=3-symbol;
                                H_LI(:,symbol,:,:)=(foreward*H_LMMSE(:,1,:,:))/14+(backward*H_LMMSE(:,40,:,:))/14;
                            end

                            if symbol>=3 && symbol<=548 % 中間
                                location=floor((symbol-3)/14)+1;
                                foreward=symbol-DMRS_ps(location);
                                backward=DMRS_ps(location+1)-symbol;
                                H_LI(:,symbol,:,:)=(foreward*H_LMMSE(:,location+1,:,:))/14+backward*H_LMMSE(:,location,:,:)/14;
                            end
                            if symbol>=549 && symbol<=560 %後邊界
                                foreward=symbol-549;
                                backward=563-symbol;
                                H_LI(:,symbol,:,:)=(foreward*H_LMMSE(:,1,:,:))/14+(backward*H_LMMSE(:,40,:,:))/14;
                            end
                        end

                        DMRS= Frame_DATA.LDPC_mod_data(2:2:1644 , 3:14:560,:);
                        DMRS_H=H_LMMSE(2:2:1644,:,:,:);
                        DMRS_hat_Y=zeros(822,40,2);
                        for carrier=1:822
                            for symbol=1:40
                                DMRS_hat_Y(carrier,symbol,:) = reshape(DMRS_H(carrier,symbol,:,:),2,2) * reshape(DMRS(carrier,symbol,:),2,1);
                            end
                        end
                        Rx1_No = sum( abs( Y_DMRS(:,:,1)  - DMRS_hat_Y(:,:,1) ).^2 ,'all' )/(40*822);
                        Rx2_No = sum( abs( Y_DMRS(:,:,2)  - DMRS_hat_Y(:,:,2) ).^2 ,'all' )/(40*822);
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%選擇detector%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        NM_Y = zeros(1644,560,2);
                        NM_H = zeros(1644,560,2,2);
                        NM_Y(:,:,1) = Y(:,:,1)./ Rx1_No;
                        NM_Y(:,:,2) = Y(:,:,2)./ Rx2_No;
                        NM_H(:,:,1,:) = H_LI(:,:,1,:) ./ Rx1_No;
                        NM_H(:,:,2,:) = H_LI(:,:,2,:) ./ Rx2_No;
                        X_hat_L=zeros(1644,560,Tx_num);
                        switch detector_case
                            case 'ZF'
                                X_hat_L=ZF(NM_Y,NM_H,Tx_num);

                            case 'LMMSE'

                                X_hat_L=LMMSE(NM_Y,NM_H,Tx_num);
                        end
                        X_hat_L=X_hat_L/NF;
                        LDPC_mod_hat= X_hat_L(DATA_Pos(1:1472256));
                        %     LDPC_mod_hat= LDPC_mod_hat(1:1472256);

                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% LLR_MOS %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                        y_i=real(LDPC_mod_hat).';       %inphase
                        y_q=imag(LDPC_mod_hat).';       %quadrature
                        y_LLR=zeros(q_bit*length(LDPC_mod_hat),1);

                        y_LLR(1:4:length(y_LLR))=(1/(2*No))*(min((y_i-  1) .^2,( y_i-  3) .^2 )-min((y_i-(-1)).^2,( y_i-(-3)).^ 2));
                        y_LLR(2:4:length(y_LLR))=(1/(2*No))*(min((y_i-(-1)).^2,( y_i-  1) .^2 )-min((y_i-(-3)).^2,( y_i-  3 ).^ 2));
                        y_LLR(3:4:length(y_LLR))=(1/(2*No))*(min((y_q-(-1)).^2,( y_q-(-3)).^2 )-min((y_q-  1) .^2,( y_q-  3 ).^ 2));
                        y_LLR(4:4:length(y_LLR))=(1/(2*No))*(min((y_q-(-1)).^2,( y_q-  1) .^2 )-min((y_q-(-3)).^2,( y_q-  3 ).^ 2));
                        y_LLR=reshape(y_LLR,1296,4544);
                        LLR_part= reshape(y_LLR,4544,1296);
                        LLR_part= permute(LLR_part,[2,1]);
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 解碼 %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                        LLR_OMS= LDPC_OMS_re(LLR_part,H_row_master,H_row_master_size,4544);%OMS
                        cat_bin_hat_LDPC = reshape(LLR_OMS(1:648,:).'<0,[],8);

                        Times=toc;

                        cat_Origin=imread('cat.jpg');
                        cat_Origin=imresize(cat_Origin,[350,350]);
                        cat_Ori_binary=[dec2bin(cat_Origin(:,:,1));dec2bin(cat_Origin(:,:,2));dec2bin(cat_Origin(:,:,3))];
                        cat_size=size(cat_Ori_binary,1);
                        cat_row=size(cat_Origin,1);         %圖片的長寬
                        cat_col=size(cat_Origin,2);
                        cat_Csize=cat_row*cat_col;

                        BER_LDPC=0;
                        cat_Ori_binary2=zeros(368064,8);
                        cat_Ori_binary2(1:367500,:)=cat_Ori_binary(1:367500,:)-'0';
                        BER_LDPC=BER_LDPC+sum((cat_Ori_binary2)~=cat_bin_hat_LDPC,'all');

                        cat_bin_hat_LDPC = cat_bin_hat_LDPC(1:cat_size,:);
                        cat_bin_hat_LDPC = sum(cat_bin_hat_LDPC.*bin_table,2);%bin2dec
                        cat_RGB_LDPC= zeros(cat_row,cat_col,3);
                        cat_RGB_LDPC(:,:,1) = reshape( cat_bin_hat_LDPC(            1:cat_Csize  ) ,cat_row,cat_col);
                        cat_RGB_LDPC(:,:,2) = reshape( cat_bin_hat_LDPC(cat_Csize  +1:cat_Csize*2) ,cat_row,cat_col);
                        cat_RGB_LDPC(:,:,3) = reshape( cat_bin_hat_LDPC(cat_Csize*2+1:cat_Csize*3) ,cat_row,cat_col);
                        %     imshow( uint8(cat_RGB_LDPC) );
                        %     BER_LDPC=0;
                        %     BER_LDPC=BER_LDPC+sum((cat_Ori_binary-'0')~=cat_bin_hat_LDPC,'all');

                        %計算capacity
                        SNR = 1/No;
                        Capacity=0;
                        for carrier = 1:1644
                            for slot = 1:560
                                unit_H = reshape(H_LI(carrier,slot,:,:),Rx_num,Tx_num);
                                Capacity=Capacity+abs(log2( det( eye(Tx_num)+ (1/Tx_num).* SNR .* (unit_H*unit_H'))));
                            end
                        end

                        SNR_rx1 =-10*log10(Rx1_No/ mean(abs(H_LMMSE(:,:,:,1) ).^2,'all'));
                        SNR_rx2 =-10*log10(Rx1_No/ mean(abs(H_LMMSE(:,:,:,2) ).^2,'all'));


                        Capacity_sum_all_LDPC = Capacity_sum_all_LDPC + Capacity;
                        

                        BER = BER_LDPC/numel(Frame_DATA.cat_Ori_binary);
                        app.LDPCt.Text = num2str(Times);                                %%用時間
                        app.LDPC_BER.Text = num2str(BER ,'%2.2e');                       %%BER
                        app.LDPCTp.Text = num2str(numel(Frame_DATA.cat_Ori_binary)*(1-BER)/(Times*10^3),'%.1f');%%throughtput
                        app.LDPC_C.Text = num2str(Capacity /(1644*560) );
                        app.QAM_C.Text = num2str(Capacity /(1644*560) );
                        imshow(uint8(cat_RGB_LDPC),'Parent',app.LDPC_PHOTO);
                        pause(0.01);

                        sum_time_LDPC= sum_time_LDPC+ Times;
                        biterror_all_LDPC = biterror_all_LDPC + BER;

                        app.SNR_RX2.Text = num2str(SNR_rx2);
                        app.SNR_RX1.Text = num2str(SNR_rx1);


                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% QAM %%%%%%%%%%%%
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


                        tic
                        PSS_GB_2=[zeros(202,1);Frame_DATA.PSS(1:822);0;Frame_DATA.PSS(823:end);zeros(201,1)];
                        PSS_t_2 = ifft(ifftshift(PSS_GB_2,1))*sqrt(2048);
                        [corr2(1,:),corr_pos1_2]=xcorr(Rx_signal(1,1:2457600/2),PSS_t_2);
                        [corr2(2,:),corr_pos2_2]=xcorr(Rx_signal(2,1:2457600/2),PSS_t_2);
                        [~, startpositon1_2]=max(abs(corr2(1,:)));
                        [~, startpositon2_2]=max(abs(corr2(2,:)));
                        y_pos1_2=corr_pos1_2(startpositon1_2)-8977;%8913
                        y_pos2_2=corr_pos2_2(startpositon2_2)-8977;%8977
                        y_synb_2=zeros(2,1228800);
                        y_synb_2(1,:)=Rx_signal(1,y_pos1_2:y_pos1_2+1228800-1);
                        y_synb_2(2,:)=Rx_signal(2,y_pos2_2:y_pos2_2+1228800-1);%%大小2*1228800

                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% CFO %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        t_2 = (10e-3/1228800):(10e-3/1228800):10e-3;
                        y_synb_2= exp(1i*2*pi*delta_f*t_2).*y_synb_2;

                        index1 = 1;
                        CFO_sum= 0;
                        CP_num= 0;
                        for symbol = 1:560
                            if (mod(symbol,28)-1)
                                CFO_head=y_synb_2(:,index1: index1+143);
                                CFO_tail=y_synb_2(:, index1+2048: index1+143+2048 );
                                CFO_sum=CFO_sum+ sum(CFO_head .* (CFO_tail').','all');
                                CP_num=CP_num + 144;
                                index1=index1 + 144 + 2048;
                            else
                                CFO_head=y_synb_2( :,index1: index1+207 );
                                CFO_tail=y_synb_2( :,index1+2048: index1+207+2048 );
                                CFO_sum=CFO_sum+ sum(CFO_head .* (CFO_tail').','all');
                                CP_num=CP_num + 208;
                                index1=index1+208+2048;
                            end

                        end
                        %偏移乘回來
                        hat_delta_f_2 =-(Frame_DATA.Fs/2048)*angle(CFO_sum/CP_num)/(2*pi) ;
                        y_syna_2=y_synb_2./exp(1i*2*pi*hat_delta_f_2*t_2);

                        %移除CP
                        yhasnoCP_2= zeros(2048,560,Tx_num);
                        index1  = 1;
                        for symbol = 1:560
                            if (mod(symbol,28)-1)
                                for k = 1:Rx_num
                                    yhasnoCP_2(:,symbol,k) = y_syna_2(k,index1+144:index1+144+2048-1);
                                end
                                index1  = index1+144+2048;
                            else
                                for k = 1:Rx_num
                                    yhasnoCP_2(:,symbol,k) = y_syna_2(k,index1+208:index1+208+2048-1);
                                end
                                index1  = index1 +208+2048;
                            end
                        end
                        %FFT----以下為頻域
                        Y_fft_2 = fftshift( fft( yhasnoCP_2/sqrt(2048) ) ,1);
                        %rm GB
                        Y_2= [ Y_fft_2( 203:1024,:,:) ; Y_fft_2( 1026:1847,:,:) ];
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%通道估測%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        Y_DMRS_2=Y_2(2:2:1644,3:14:560,:);

                        P0 = eye(822);
                        P1 = ones(822,1);
                        P1(1:2:822)=-1;
                        P1 = diag(P1);
                        H_LMMSE_2=zeros(1644,40,2,2);
                        H_LMMSE_2(:,:,1,1)=R_H_HD*P0'*inv(P0*R_HD_HD*P0'+P1*R_HD_HD*P1'+(1./SNR_Power).*P0)*(DMRS_value'.*Y_DMRS_2(:,:,1));
                        H_LMMSE_2(:,:,2,1)=R_H_HD*P0'*inv(P0*R_HD_HD*P0'+P1*R_HD_HD*P1'+(1./SNR_Power).*P0)*(DMRS_value'.*Y_DMRS_2(:,:,2));
                        H_LMMSE_2(:,:,1,2)=R_H_HD*P1'*inv(P0*R_HD_HD*P0'+P1*R_HD_HD*P1'+(1./SNR_Power).*P0)*(DMRS_value'.*Y_DMRS_2(:,:,1));
                        H_LMMSE_2(:,:,2,2)=R_H_HD*P1'*inv(P0*R_HD_HD*P0'+P1*R_HD_HD*P1'+(1./SNR_Power).*P0)*(DMRS_value'.*Y_DMRS_2(:,:,2));
                        %線性內差
                        H_LI_2=zeros(1644,560,2,2);
                        DMRS_ps_2=3:14:560;
                        for symbol=1:560
                            if symbol>=1 && symbol<=2  %前邊界
                                foreward_2=11+symbol;
                                backward_2=3-symbol;
                                H_LI_2(:,symbol,:,:)=(foreward_2*H_LMMSE_2(:,1,:,:))/14+(backward_2*H_LMMSE_2(:,40,:,:))/14;
                            end

                            if symbol>=3 && symbol<=548 % 中間
                                location=floor((symbol-3)/14)+1;
                                foreward_2=symbol-DMRS_ps_2(location);
                                backward_2=DMRS_ps_2(location+1)-symbol;
                                H_LI_2(:,symbol,:,:)=(foreward_2*H_LMMSE_2(:,location+1,:,:))/14+backward_2*H_LMMSE_2(:,location,:,:)/14;
                            end
                            if symbol>=549 && symbol<=560 %後邊界
                                foreward_2=symbol-549;
                                backward_2=563-symbol;
                                H_LI_2(:,symbol,:,:)=(foreward_2*H_LMMSE_2(:,1,:,:))/14+(backward_2*H_LMMSE_2(:,40,:,:))/14;
                            end
                        end

                        DMRS_2= Frame_DATA.LDPC_mod_data(2:2:1644 , 3:14:560,:);
                        DMRS_H_2=H_LMMSE_2(2:2:1644,:,:,:);
                        DMRS_hat_Y_2=zeros(822,40,2);
                        for carrier=1:822
                            for symbol=1:40
                                DMRS_hat_Y_2(carrier,symbol,:) = reshape(DMRS_H_2(carrier,symbol,:,:),2,2) * reshape(DMRS_2(carrier,symbol,:),2,1);
                            end
                        end
                        Rx1_No_2 = sum( abs( Y_DMRS_2(:,:,1)  - DMRS_hat_Y_2(:,:,1) ).^2 ,'all' )/(40*822);
                        Rx2_No_2 = sum( abs( Y_DMRS_2(:,:,2)  - DMRS_hat_Y_2(:,:,2) ).^2 ,'all' )/(40*822);
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%選擇detector%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        NM_Y_2 = zeros(1644,560,2);
                        NM_H_2 = zeros(1644,560,2,2);
                        NM_Y_2(:,:,1) = Y_2(:,:,1)./ Rx1_No_2;
                        NM_Y_2(:,:,2) = Y_2(:,:,2)./ Rx2_No_2;
                        NM_H_2(:,:,1,:) = H_LI_2(:,:,1,:) ./ Rx1_No_2;
                        NM_H_2(:,:,2,:) = H_LI_2(:,:,2,:) ./ Rx2_No_2;
                        X_hat_L_2=zeros(1644,560,Tx_num);
                        switch detector_case
                            case 'ZF'
                                X_hat_L_2=ZF(NM_Y_2,NM_H_2,Tx_num);

                            case 'LMMSE'

                                X_hat_L_2=LMMSE(NM_Y_2,NM_H_2,Tx_num);
                        end
                        X_hat_L_2=X_hat_L_2/NF;
                        LDPC_mod_hat_2= X_hat_L_2(DATA_Pos(1:1472256));
                        %     LDPC_mod_hat= LDPC_mod_hat(1:1472256);
                        QAM_dec_L_hat = qamdemod(LDPC_mod_hat_2,QAM ,'gray');
                        QAM_bin_L_hat = reshape(dec2bin (QAM_dec_L_hat,q_bit).'-'0',[],1) ;
                        LDPC_bin_part  = permute(reshape(QAM_bin_L_hat,[],1296) ,[2,1]);
                        cat_bin_hat   = reshape(LDPC_bin_part(1:648,:).',[],8);
                        Times2=toc;


                        cat_Origin=imread('cat.jpg');
                        cat_Origin=imresize(cat_Origin,[350,350]);
                        cat_Ori_binary=[dec2bin(cat_Origin(:,:,1));dec2bin(cat_Origin(:,:,2));dec2bin(cat_Origin(:,:,3))];
                        %                     cat_size=size(cat_Ori_binary,1);
                        %                     cat_row=size(cat_Origin,1);         %圖片的長寬
                        %                     cat_col=size(cat_Origin,2);
                        %                     cat_Csize=cat_row*cat_col;

                        BER_QAM=0;
                        cat_Ori_binary2=zeros(368064,8);
                        cat_Ori_binary2(1:367500,:)=cat_Ori_binary(1:367500,:)-'0';
                        BER_QAM=BER_QAM+sum((cat_Ori_binary2)~=cat_bin_hat,'all');

                        cat_bin_hat = cat_bin_hat(1:cat_size,:);
                        cat_bin_hat = sum(cat_bin_hat.*bin_table,2);%bin2dec
                        cat_RGB_LDPC2= zeros(cat_row,cat_col,3);
                        cat_RGB_LDPC2(:,:,1) = reshape( cat_bin_hat(            1:cat_Csize  ) ,cat_row,cat_col);
                        cat_RGB_LDPC2(:,:,2) = reshape( cat_bin_hat(cat_Csize  +1:cat_Csize*2) ,cat_row,cat_col);
                        cat_RGB_LDPC2(:,:,3) = reshape( cat_bin_hat(cat_Csize*2+1:cat_Csize*3) ,cat_row,cat_col);
                        
                        BER2 = BER_QAM/numel(Frame_DATA.cat_Ori_binary);
                        app.QAMt.Text = num2str(Times2);                                %%用時間
                        app.QAM_BER.Text = num2str(BER2 ,'%2.2e');                       %%BER
                        app.QAMTp.Text = num2str(numel(Frame_DATA.cat_Ori_binary)*(1-BER2)/(Times*10^3),'%.1f');%%throughtput
                        %                     app.QAM_C.Text = num2str(Capacity /(1644*560) );
                        imshow(uint8(cat_RGB_LDPC2),'Parent',app.QAM_demodulate_PHOTO);
                        pause(0.01);

                        sum_time_QAM= sum_time_QAM+ Times;
                        biterror_all_QAM = biterror_all_QAM + BER2;

                    end
%                     BER_avg = biterror_all_QAM/(numel(Frame_DATA.cat_Ori_binary)*app.Time.Value);
                    BER_avg = biterror_all_QAM/(1*app.Time.Value);
                    BER_avg_LDPC= biterror_all_LDPC/(1*app.Time.Value);

                    app.avgQAMt.Text  = num2str(sum_time_QAM/app.Time.Value);
                    app.avgLDPCt.Text = num2str(sum_time_LDPC/app.Time.Value);

                    app.avgQAMBER.Text = num2str( BER_avg,'%2.2e');
                    app.avgLDPCBER.Text = num2str( BER_avg_LDPC ,'%2.2e');
                    app.avgQAMTp.Text = num2str(app.Time.Value * numel(Frame_DATA.cat_Ori_binary)*(1-BER_avg)/(sum_time_QAM*10^3) ,'%.1f');
                    app.avgLDPCTp.Text = num2str(app.Time.Value * numel(Frame_DATA.cat_Ori_binary)*(1-BER_avg_LDPC)/(sum_time_LDPC*10^3),'%.1f');
            end
        end

        % Button down function: LDPC_PHOTO
        function LDPC_PHOTOButtonDown(app, event)
            axis(app.UIAxes,'off');
        end

        % Button down function: QAM_demodulate_PHOTO
        function QAM_demodulate_PHOTOButtonDown(app, event)
            axis(app.UIAxes,'off');
        end

        % Button down function: Original_PHOTO
        function Original_PHOTOButtonDown(app, event)
            axis(app.UIAxes,'off');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.QAM_demodulate_PHOTO = uiaxes(app.UIFigure);
            title(app.QAM_demodulate_PHOTO, 'QAM demodulate')
            app.QAM_demodulate_PHOTO.XTick = [];
            app.QAM_demodulate_PHOTO.YTick = [];
            app.QAM_demodulate_PHOTO.ButtonDownFcn = createCallbackFcn(app, @QAM_demodulate_PHOTOButtonDown, true);
            app.LDPC_PHOTO = uiaxes(app.UIFigure);
            title(app.LDPC_PHOTO, 'LDPC')
            app.LDPC_PHOTO.XTick = [];
            app.LDPC_PHOTO.YTick = [];
            app.LDPC_PHOTO.ButtonDownFcn = createCallbackFcn(app, @LDPC_PHOTOButtonDown, true);
            app.Original_PHOTO = uiaxes(app.UIFigure);
            title(app.Original_PHOTO, 'Origianl')
            app.Original_PHOTO.XTick = [];
            app.Original_PHOTO.YTick = [];
            app.Original_PHOTO.ButtonDownFcn = createCallbackFcn(app, @Original_PHOTOButtonDown, true);
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.SetupPanel = uipanel(app.UIFigure);
            app.SetupPanel.Title = 'Set up';
            app.DetectorDropDownLabel = uilabel(app.SetupPanel);
            app.DetectorDropDownLabel.Text = 'Detector';
            app.DetectorChoose = uidropdown(app.SetupPanel);
            app.DetectorChoose.Items = {'LMMSE', 'ZF'};
            app.DetectorChoose.Value = 'LMMSE';
            app.Label = uilabel(app.SetupPanel);
            app.Label.Text = 'mode';
            app.mode = uidropdown(app.SetupPanel);
            app.mode.Items = {'simulation', 'OTA'};
            app.mode.Value = 'simulation';
            app.dBLabel_3 = uilabel(app.SetupPanel);
            app.dBLabel_3.Text = 'dB';
            app.WindowEditFieldLabel_2 = uilabel(app.SetupPanel);
            app.WindowEditFieldLabel_2.Text = 'Window';
            app.Window = uieditfield(app.SetupPanel, 'numeric');
            app.Window.Value = 10;
            app.SNRweightEditFieldLabel_2 = uilabel(app.SetupPanel);
            app.SNRweightEditFieldLabel_2.Text = 'SNR weight';
            app.SNRweight = uieditfield(app.SetupPanel, 'numeric');
            app.SNRweight.Value = 45;
            app.TimeEditFieldLabel = uilabel(app.SetupPanel);
            app.TimeEditFieldLabel.Text = 'Time';
            app.Time = uieditfield(app.SetupPanel, 'numeric');
            app.Time.Value = 11;
            app.SNREditFieldLabel = uilabel(app.SetupPanel);
            app.SNREditFieldLabel.Text = 'SNR';
            app.SNR = uieditfield(app.SetupPanel, 'numeric');
            app.SNR.Value = 20;
            app.QAMdemodPanel = uipanel(app.UIFigure);
            app.QAMdemodPanel.Title = 'QAM demod';
            app.BERLabel = uilabel(app.QAMdemodPanel);
            app.BERLabel.Text = 'BER : ';
            app.ThroughputLabel = uilabel(app.QAMdemodPanel);
            app.ThroughputLabel.Text = 'Throughput : ';
            app.TimeLabel = uilabel(app.QAMdemodPanel);
            app.TimeLabel.Text = 'Time : ';
            app.kbpsLabel = uilabel(app.QAMdemodPanel);
            app.kbpsLabel.Text = 'kbps';
            app.secLabel = uilabel(app.QAMdemodPanel);
            app.secLabel.Text = 'sec';
            app.QAMt = uilabel(app.QAMdemodPanel);
            app.QAMt.Text = '';
            app.QAMTp = uilabel(app.QAMdemodPanel);
            app.QAMTp.Text = '';
            app.CapacityLabel = uilabel(app.QAMdemodPanel);
            app.CapacityLabel.Text = 'Capacity :';
            app.QAM_C = uilabel(app.QAMdemodPanel);
            app.QAM_C.Text = '';
            app.QAM_BER = uilabel(app.QAMdemodPanel);
            app.QAM_BER.Text = '';
            app.LDPCPanel = uipanel(app.UIFigure);
            app.LDPCPanel.Title = 'LDPC';
            app.TimeLabel_2 = uilabel(app.LDPCPanel);
            app.TimeLabel_2.Text = 'Time : ';
            app.ThroughputLabel_2 = uilabel(app.LDPCPanel);
            app.ThroughputLabel_2.Text = 'Throughput : ';
            app.BERLabel_2 = uilabel(app.LDPCPanel);
            app.BERLabel_2.Text = 'BER : ';
            app.kbpsLabel_2 = uilabel(app.LDPCPanel);
            app.kbpsLabel_2.Text = 'kbps';
            app.secLabel_2 = uilabel(app.LDPCPanel);
            app.secLabel_2.Text = 'sec';
            app.LDPCTp = uilabel(app.LDPCPanel);
            app.LDPCTp.Text = '';
            app.LDPCt = uilabel(app.LDPCPanel);
            app.LDPCt.Text = '';
            app.CapacityLabel_2 = uilabel(app.LDPCPanel);
            app.CapacityLabel_2.Text = 'Capacity :';
            app.LDPC_C = uilabel(app.LDPCPanel);
            app.LDPC_C.Text = '';
            app.LDPC_BER = uilabel(app.LDPCPanel);
            app.LDPC_BER.Text = '';
            app.SNRPanel = uipanel(app.UIFigure);
            app.SNRPanel.Title = 'SNR';
            app.Rx1SNRLabel = uilabel(app.SNRPanel);
            app.Rx1SNRLabel.Text = 'Rx1 SNR : ';
            app.Rx2SNRLabel = uilabel(app.SNRPanel);
            app.Rx2SNRLabel.Text = 'Rx2 SNR : ';
            app.dBLabel = uilabel(app.SNRPanel);
            app.dBLabel.Text = 'dB';
            app.dBLabel_2 = uilabel(app.SNRPanel);
            app.dBLabel_2.Text = 'dB';
            app.SNR_RX2 = uilabel(app.SNRPanel);
            app.SNR_RX2.Text = '';
            app.SNR_RX1 = uilabel(app.SNRPanel);
            app.SNR_RX1.Text = '';
            app.AvgQAMdemodPanel = uipanel(app.UIFigure);
            app.AvgQAMdemodPanel.Title = 'Avg QAM demod';
            app.BERLabel_3 = uilabel(app.AvgQAMdemodPanel);
            app.BERLabel_3.Text = 'BER : ';
            app.ThroughtputLabel_3 = uilabel(app.AvgQAMdemodPanel);
            app.ThroughtputLabel_3.Text = 'Throughtput : ';
            app.TimeLabel_3 = uilabel(app.AvgQAMdemodPanel);
            app.TimeLabel_3.Text = 'Time : ';
            app.kbpsLabel_3 = uilabel(app.AvgQAMdemodPanel);
            app.kbpsLabel_3.Text = 'kbps';
            app.secLabel_3 = uilabel(app.AvgQAMdemodPanel);
            app.secLabel_3.Text = 'sec';
            app.avgQAMBER = uilabel(app.AvgQAMdemodPanel);
            app.avgQAMBER.Text = '';
            app.avgQAMTp = uilabel(app.AvgQAMdemodPanel);
            app.avgQAMTp.Text = '';
            app.avgQAMt = uilabel(app.AvgQAMdemodPanel);
            app.avgQAMt.Text = '';
            app.AvgLDPCPanel = uipanel(app.UIFigure);
            app.AvgLDPCPanel.Title = 'Avg LDPC';
            app.BERLabel_4 = uilabel(app.AvgLDPCPanel);
            app.BERLabel_4.Text = 'BER : ';
            app.ThroughtputLabel_4 = uilabel(app.AvgLDPCPanel);
            app.ThroughtputLabel_4.Text = 'Throughtput : ';
            app.TimeLabel_4 = uilabel(app.AvgLDPCPanel);
            app.TimeLabel_4.Text = 'Time : ';
            app.kbpsLabel_4 = uilabel(app.AvgLDPCPanel);
            app.kbpsLabel_4.Text = 'kbps';
            app.secLabel_4 = uilabel(app.AvgLDPCPanel);
            app.secLabel_4.Text = 'sec';
            app.avgLDPCBER = uilabel(app.AvgLDPCPanel);
            app.avgLDPCBER.Text = '';
            app.avgLDPCTp = uilabel(app.AvgLDPCPanel);
            app.avgLDPCTp.Text = '';
            app.avgLDPCt = uilabel(app.AvgLDPCPanel);
            app.avgLDPCt.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end